classdef Linear < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Information             matlab.ui.control.Button
        thPanel                 matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        DliuPanel               matlab.ui.container.Panel
        MatrnOutputLabel        matlab.ui.control.Label
        MatrnInputLabel         matlab.ui.control.Label
        OutputTable             matlab.ui.control.Table
        InputTable              matlab.ui.control.Table
        Panel                   matlab.ui.container.Panel
        ConfirmButton           matlab.ui.control.Button
        Label_2                 matlab.ui.control.Label
        HeadDropDown            matlab.ui.control.DropDown
        TailDropDown            matlab.ui.control.DropDown
        uiDropDownLabel         matlab.ui.control.Label
        uDropDownLabel          matlab.ui.control.Label
        ngicntmLabel            matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        NumofVerticesEditField  matlab.ui.control.NumericEditField
        SnhEditFieldLabel       matlab.ui.control.Label
        NumofEdgesEditField     matlab.ui.control.NumericEditField
        ShngvctLabel            matlab.ui.control.Label
        CalculateButton         matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        CreateTableButton       matlab.ui.control.Button
        UndirectedButton        matlab.ui.control.Button
        DirectedButton          matlab.ui.control.Button
        Name                    matlab.ui.control.Label
        Group                   matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UndirectedButton
        function UndirectedButtonPushed(app, event)
            app.DirectedButton.Enable="off";
            app.ResetButton.Enable="on";

            app.NumofEdgesEditField.Enable="on";
            app.NumofVerticesEditField.Enable="on";
            app.CreateTableButton.Enable="on";

            app.UndirectedButton.BackgroundColor=[0.07,0.62,1.00];
        end

        % Button pushed function: DirectedButton
        function DirectedButtonPushed(app, event)
            app.UndirectedButton.Enable="off";
            app.ResetButton.Enable="on";

            app.NumofEdgesEditField.Enable="on";
            app.NumofVerticesEditField.Enable="on";
            app.CreateTableButton.Enable="on";

            app.DirectedButton.BackgroundColor=[0.07,0.62,1.00];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UndirectedButton.Enable="on";
            app.DirectedButton.Enable="on";
            app.CalculateButton.Enable="off";
            app.ResetButton.Enable="off";

            app.NumofEdgesEditField.Enable="off";
            app.NumofVerticesEditField.Enable="off";
            app.CreateTableButton.Enable="off";

            app.DirectedButton.BackgroundColor=[0.96,0.96,0.96];
            app.UndirectedButton.BackgroundColor=[0.96,0.96,0.96];

            data = cell(0, 0);
            app.InputTable.Data = data;
            app.OutputTable.Data = data;

            app.NumofEdgesEditField.Value=0;
            app.NumofVerticesEditField.Value=0;

            cla(app.UIAxes,'reset')
        end

        % Button pushed function: CreateTableButton
        function CreateTableButtonPushed(app, event)
            app.CreateTableButton.Enable = "off";
            app.CalculateButton.Enable="on";
            app.NumofEdgesEditField.Enable="off";
            app.NumofVerticesEditField.Enable="off";
            app.HeadDropDown.Enable="on";
            app.TailDropDown.Enable="on";
            app.ConfirmButton.Enable="on";
                      
            Num = round(app.NumofEdgesEditField.Value);

            datain = cell(Num, 3);
            dataout = cell(Num, Num);

            app.InputTable.Data = datain;
            app.OutputTable.Data = dataout;

            Names = arrayfun(@(x) sprintf('%d', x), 1:Num, 'UniformOutput', false);
            app.OutputTable.ColumnName = Names;
            app.OutputTable.RowName = Names;
            app.InputTable.RowName = Names;

            Names = arrayfun(@(x) sprintf('%d', x), 1:3, 'UniformOutput', false);
            app.InputTable.ColumnName = Names;

            app.OutputTable.Enable = "off";
            app.OutputTable.Enable="inactive";
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            data = app.InputTable.Data;
            
            fileID = fopen('input.txt', 'w');

            fprintf(fileID, '%s ', string(app.NumofVerticesEditField.Value));
            fprintf(fileID, '%s ', string(app.NumofEdgesEditField.Value));
            if app.DirectedButton.Enable == 1
                fprintf(fileID, '%s ', string(1));
            elseif app.UndirectedButton.Enable == 1
                fprintf(fileID, '%s ', string(0));
            end
            fprintf(fileID, '\n');

            [rows, cols] = size(data);
            for i = 1:rows
                for j = 1:cols
                    fprintf(fileID, '%s ', string(data{i, j}));
                end
                fprintf(fileID, '\n');
            end
            fclose(fileID);
            

            x = str2double(data(:, 1));
            y = str2double(data(:, 2));
            z = str2double(data(:, 3));
            
            numVertices = app.NumofVerticesEditField.Value;

            if app.DirectedButton.Enable == 1
                G = digraph(x, y, z, numVertices);
            elseif app.UndirectedButton.Enable == 1
                G = graph(x, y, z, numVertices);
            end

            app.OutputTable.Enable="off";
            plot(app.UIAxes, G, 'EdgeLabel', G.Edges.Weight);

            %system('run.exe')
            %OutputData = readmatrix('Output.txt');
            %app.OutputTable.Data = OutputData;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.HeadDropDown.Enable="off";
            app.TailDropDown.Enable="off";
            app.ConfirmButton.Enable="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Group = uilabel(app.UIFigure);
            app.Group.Text = 'CN01 - Nhóm 8';
            app.Name = uilabel(app.UIFigure);
            app.Name.Text = 'ĐỀ TÀI 08: THUẬT TOÁN FLOYD - WARSHALL TÌM ĐƯỜNG ĐI NGẮN NHẤT';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.DirectedButton = uibutton(app.Panel, 'push');
            app.DirectedButton.ButtonPushedFcn = createCallbackFcn(app, @DirectedButtonPushed, true);
            app.DirectedButton.Text = 'Đồ thị có hướng';
            app.UndirectedButton = uibutton(app.Panel, 'push');
            app.UndirectedButton.ButtonPushedFcn = createCallbackFcn(app, @UndirectedButtonPushed, true);
            app.UndirectedButton.Text = 'Đồ thị vô hướng';
            app.CreateTableButton = uibutton(app.Panel, 'push');
            app.CreateTableButton.ButtonPushedFcn = createCallbackFcn(app, @CreateTableButtonPushed, true);
            app.CreateTableButton.Enable = 'off';
            app.CreateTableButton.Text = 'Tạo bảng';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Tính toán';
            app.ShngvctLabel = uilabel(app.Panel);
            app.ShngvctLabel.Text = 'Số cạnh';
            app.NumofEdgesEditField = uieditfield(app.Panel, 'numeric');
            app.NumofEdgesEditField.RoundFractionalValues = 'on';
            app.NumofEdgesEditField.Enable = 'off';
            app.SnhEditFieldLabel = uilabel(app.Panel);
            app.SnhEditFieldLabel.Text = 'Số đỉnh';
            app.NumofVerticesEditField = uieditfield(app.Panel, 'numeric');
            app.NumofVerticesEditField.Enable = 'off';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '__________________';
            app.ngicntmLabel = uilabel(app.Panel);
            app.ngicntmLabel.Text = 'Đường đi cần tìm:';
            app.uDropDownLabel = uilabel(app.Panel);
            app.uDropDownLabel.Text = 'Đầu';
            app.uiDropDownLabel = uilabel(app.Panel);
            app.uiDropDownLabel.Text = 'Đuôi';
            app.TailDropDown = uidropdown(app.Panel);
            app.TailDropDown.Items = {''};
            app.TailDropDown.Enable = 'off';
            app.TailDropDown.Value = '';
            app.HeadDropDown = uidropdown(app.Panel);
            app.HeadDropDown.Items = {''};
            app.HeadDropDown.Enable = 'off';
            app.HeadDropDown.Value = '';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '__________________';
            app.ConfirmButton = uibutton(app.Panel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Enable = 'off';
            app.ConfirmButton.Text = 'Xác nhận';
            app.DliuPanel = uipanel(app.UIFigure);
            app.DliuPanel.Title = 'Dữ liệu';
            app.InputTable = uitable(app.DliuPanel);
            app.InputTable.ColumnName = {'1'; '2'; '3'; '4'};
            app.InputTable.RowName = {'1,2,3,4'};
            app.InputTable.ColumnEditable = true;
            app.OutputTable = uitable(app.DliuPanel);
            app.OutputTable.ColumnName = {'1'; '2'; '3'; '4'};
            app.OutputTable.RowName = {'1,2,3,4'};
            app.OutputTable.ColumnEditable = true;
            app.OutputTable.Enable = 'off';
            app.MatrnInputLabel = uilabel(app.DliuPanel);
            app.MatrnInputLabel.Text = 'Ma trận Input';
            app.MatrnOutputLabel = uilabel(app.DliuPanel);
            app.MatrnOutputLabel.Text = 'Ma trận Output';
            app.thPanel = uipanel(app.UIFigure);
            app.thPanel.Title = 'Đồ thị';
            app.UIAxes = uiaxes(app.thPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.Information = uibutton(app.UIFigure, 'push');
            app.Information.Text = 'i';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Linear

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end